classdef ImageAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImageTab                      matlab.ui.container.Tab
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        PauseContinueButton           matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SettingsTab                   matlab.ui.container.Tab
        TriggermodeDropDown           matlab.ui.control.DropDown
        TriggermodeDropDownLabel      matlab.ui.control.Label
        AcquisitiontimeoutsSpinner    matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        AcquisitionmodeDropDown       matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel  matlab.ui.control.Label
        ExposuresSpinner              matlab.ui.control.Spinner
        ExposuresSpinnerLabel         matlab.ui.control.Label
        SelectcameraButtonGroup       matlab.ui.container.ButtonGroup
        ZeluxButton                   matlab.ui.control.RadioButton
        Andor19331Button              matlab.ui.control.RadioButton
        Andor19330Button              matlab.ui.control.RadioButton
        BackgroundshotCheckBox        matlab.ui.control.CheckBox
        DatasetsizeSpinner            matlab.ui.control.Spinner
        DatasetsizeSpinnerLabel       matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        ImageMenu                     matlab.ui.container.Menu
        BackgroundMenu                matlab.ui.container.Menu
        SignalMenu                    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Live = struct()
        Data = struct()
        Layout = dictionary()
        Handle = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start(app, event)
            
            % Initialize the acquisition
            initializeAcquisition(app)
            tic

            % Run continuously until aborted
            while ~app.StopButton.Value
                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    toc
                    tic
                    
                    app.StatusLamp.Color = [1, 1, 0];
                    
                    % Acquire data
                    image = acquireImage(app);
                    if app.BackgroundshotCheckBox.Value
                        background = acquireImage(app);
                    end
                    
                    app.StatusLamp.Color = [0, 1, 0];

                    % Update data storage
                    app.Live.Current = app.Live.Current + 1;
                    if app.Live.Current <= app.Data.Config.MaxImage
                        app.Data.Image(:,:,app.Live.Current) = image;
                        if app.Data.Config.Background
                            app.Data.Background(:,:,app.Live.Current) = background;
                        end
                    else
                        % If the current shot is above the max, shift the data storage
                        
                    end

                    % Update layout
                    imagesc(app.UIAxes, image)
                    app.UIAxes.DataAspectRatio = [1, 1, 1];
                    app.UIAxes.Title.String = sprintf('Current image: %d/%d', app.Live.Current, app.Data.Config.MaxImage);
                    colorbar(app.UIAxes)

                    imagesc(app.UIAxes_2, background)
                    app.UIAxes_2.DataAspectRatio = [1, 1, 1];
                    app.UIAxes_2.Title.String = 'Current background';
                    colorbar(app.UIAxes_2)
                    drawnow
                    
                    pause(0.001)
                end

                pause(0.001)
            end

            fprintf('Acquisition stopped\n')
            app.StatusLamp.Color = [1, 0, 0];
            
            if ~isempty(app.Handle)
                closeZelux(app.Handle{:})
                app.Handle = {};
            end
            app.StopButton.Value = false;
            
        end

        % Close request function: UIFigure
        function closeUI(app, event)
            if ~isempty(app.Handle)
                closeZelux(app.Handle{:})
            end
            delete(app)
        end

        % Button pushed function: SaveButton
        function save(app, event)
            saveData(app.Data, app.Live)
        end

        % Menu selected function: ImageMenu
        function setContentImage(app, event)
            
        end

        % Menu selected function: BackgroundMenu
        function setContentBackground(app, event)
            
        end

        % Menu selected function: SignalMenu
        function setContentSignal(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeUI, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UIAxes = uiaxes(app.ImageTab);
            app.UIAxes_2 = uiaxes(app.ImageTab);
            title(app.UIAxes_2, ' ')
            app.UIAxes2 = uiaxes(app.ImageTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.ImageTab);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.StartButton = uibutton(app.ImageTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.ImageTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.StopButton = uibutton(app.ImageTab, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseContinueButton = uibutton(app.ImageTab, 'state');
            app.PauseContinueButton.Text = 'Pause / Continue';
            app.StatusLampLabel = uilabel(app.ImageTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ImageTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DatasetsizeSpinnerLabel = uilabel(app.SettingsTab);
            app.DatasetsizeSpinnerLabel.Text = 'Dataset size';
            app.DatasetsizeSpinner = uispinner(app.SettingsTab);
            app.DatasetsizeSpinner.ValueDisplayFormat = '%d';
            app.DatasetsizeSpinner.Value = 20;
            app.BackgroundshotCheckBox = uicheckbox(app.SettingsTab);
            app.BackgroundshotCheckBox.Text = 'Background shot';
            app.BackgroundshotCheckBox.Value = true;
            app.SelectcameraButtonGroup = uibuttongroup(app.SettingsTab);
            app.SelectcameraButtonGroup.Title = 'Select camera';
            app.Andor19330Button = uiradiobutton(app.SelectcameraButtonGroup);
            app.Andor19330Button.Text = 'Andor 19330';
            app.Andor19331Button = uiradiobutton(app.SelectcameraButtonGroup);
            app.Andor19331Button.Text = 'Andor 19331';
            app.ZeluxButton = uiradiobutton(app.SelectcameraButtonGroup);
            app.ZeluxButton.Text = 'Zelux';
            app.ZeluxButton.Value = true;
            app.ExposuresSpinnerLabel = uilabel(app.SettingsTab);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.ExposuresSpinner = uispinner(app.SettingsTab);
            app.ExposuresSpinner.Step = 0.1;
            app.ExposuresSpinner.Value = 1e-06;
            app.AcquisitionmodeDropDownLabel = uilabel(app.SettingsTab);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.AcquisitionmodeDropDown = uidropdown(app.SettingsTab);
            app.AcquisitionmodeDropDown.Items = {'Full frame', 'FK 2', 'FK 4', 'FK 8'};
            app.AcquisitionmodeDropDown.Value = 'Full frame';
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.SettingsTab);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.SettingsTab);
            app.AcquisitiontimeoutsSpinner.Value = 20;
            app.TriggermodeDropDownLabel = uilabel(app.SettingsTab);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.SettingsTab);
            app.TriggermodeDropDown.Items = {'External', 'Internal'};
            app.TriggermodeDropDown.Value = 'External';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ImageMenu = uimenu(app.ContextMenu);
            app.ImageMenu.MenuSelectedFcn = createCallbackFcn(app, @setContentImage, true);
            app.ImageMenu.Text = 'Image';
            app.BackgroundMenu = uimenu(app.ContextMenu);
            app.BackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @setContentBackground, true);
            app.BackgroundMenu.Text = 'Background';
            app.SignalMenu = uimenu(app.ContextMenu);
            app.SignalMenu.MenuSelectedFcn = createCallbackFcn(app, @setContentSignal, true);
            app.SignalMenu.Text = 'Signal';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.UIAxes_2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end